classdef MelanomaDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_7                        matlab.ui.control.Label
        HasthelesionchangedwithinthepastweeksmonthsLabel  matlab.ui.control.Label
        EvolutionDropDown              matlab.ui.control.DropDown
        EvolutionLabel                 matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Label_6                        matlab.ui.control.Label
        ChoiceofSegmentationMaskLabel  matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        ClicktobeginsegmentationonceimageisloadedLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SelectMaskDropDown             matlab.ui.control.DropDown
        SelectMaskDropDownLabel        matlab.ui.control.Label
        FinalResultsLabel              matlab.ui.control.Label
        ResultsLabel                   matlab.ui.control.Label
        LoadButton_ConfirmChoice       matlab.ui.control.Button
        LoadButton_StartDiag           matlab.ui.control.Button
        LoadButton_LoadIm              matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        ImageAxes_kMeans               matlab.ui.control.UIAxes
        ImageAxes_RegionGrowing        matlab.ui.control.UIAxes
        ImageAxes_Snake                matlab.ui.control.UIAxes
        ImageAxes_AT                   matlab.ui.control.UIAxes
        ImageAxes_Otsu                 matlab.ui.control.UIAxes
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loadedImage    % Original uploaded image
        selectedMask   % Chosen mask to move forward with
        selectedRgbMask
        otsuMask
        ATMask
        snakeMask
        regionMask
        KMask
        lastLoadedFile
        lastLoadedFullPath
    end

    methods (Access = private)
        
        function updateimage(app,imagefile)
            try
                imread(imagefile);
            catch ME
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end            
        end


        % ---------------- PREPROCESSING METHODS ---------------------
        
        function imgOut = preprocessGrayscale(~, img)
            imgGray = rgb2gray(img);
            % Median filter
            imgMed = medfilt2(imgGray, [5 5]);
            % Morphological Closing
            imgClosed = imclose(imgMed, strel('disk', 3));
            % Gaussian filter
            sigma = 1.2;
            g_filt_length = 9; 
            G = fspecial('gaussian', [g_filt_length g_filt_length], sigma);
            imgSmooth = conv2(double(imgClosed), G, 'same');
            figure('Name', 'Final Preprocessed Image (Grayscale)', 'NumberTitle', 'off');
            imshow(uint8(imgSmooth));
            title('Final Preprocessed Image (Grayscale)');
            imgOut = imgSmooth;
        end
        
        function imgOut = preprocessColor(~, img)
            % Median Filter
            imgMed = zeros(size(img), 'like', img);
            for c = 1:3
                imgMed(:,:,c) = medfilt2(img(:,:,c), [5 5]);
            end
            % Convert to Lab color space
            labMed = rgb2lab(imgMed); 
            L = labMed(:,:,1);
            % Morphological Closing on L* channel
            se = strel('disk', 3);
            L_closed = imclose(L, se);
            % Reconstruct Lab image with updated L*
            labEnhanced = labMed;
            labEnhanced(:,:,1) = L_closed;
            % Convert back to RGB
            rgbEnhanced = lab2rgb(labEnhanced);
            rgbEnhanced = im2uint8(rgbEnhanced);
            % Gaussian smoothing on each RGB channel
            sigma = 1;
            g_filt_length = 4;
            G = fspecial('gaussian', [g_filt_length g_filt_length], sigma);
            imgSmooth = zeros(size(rgbEnhanced), 'like', rgbEnhanced);
            for c = 1:3
                imgSmooth(:,:,c) = conv2(double(rgbEnhanced(:,:,c)), G, 'same');
            end
            figure('Name', 'Final Preprocessed Image (Color)', 'NumberTitle', 'off');
            imshow(imgSmooth);
            title('Final Preprocessed Image (Color)');
            imgOut = imgSmooth;
        end

        % ---------------- SEGMENTATION METHODS ----------------------

        function mask = segmentOtsu(app, grayImg, cropBox, fullSize, originalCropSize)
            normImg = uint8(mat2gray(grayImg)*255);
            level = graythresh(normImg);
            bw = imbinarize(normImg, level);
            bw = imcomplement(bw);
            bw = logical(bw);
            % Keep only the main lesion
            bw = bwareafilt(bw, 1);
            % Seal narrow background canals by WIDTH 
            %    Any background pixels within t px of the lesion are flipped to foreground.
            t = 3;
            thin_bg = (~bw) & (bwdist(bw) <= t);
            bw_sealed = bw | thin_bg;
            % Now enclosed voids are true "holes" -> fill them
            bw_filled = imfill(bw_sealed, 'holes');
            bw_smooth = imclose(bw_filled, strel('disk', 1));
            bw_smooth = bwareaopen(bw_smooth, 20);
            bw = bw_smooth;
            mask = app.reconstructFullMask(bw, cropBox, fullSize, originalCropSize);
        end
        
        function mask = segmentAdaptiveThreshold(app, grayImg, cropBox, fullSize, originalCropSize)
            [croppedRows, croppedCols] = size(grayImg);
            lowerBound = min(croppedRows,croppedCols)*0.5;
            upperBound = max(croppedRows,croppedCols)*0.07;
            blockSize = round(min(upperBound,lowerBound));
            overlap = round(blockSize / 1.3); % ~75% overlap
            step = blockSize - overlap;
            % Initialize accumulation maps
            iAdaptiveSum = zeros(size(grayImg));
            weightMap = zeros(size(grayImg));
            % Only iterate over pixels within the cropped region (ROI)
            maxRowStart = croppedRows - blockSize + 1;
            maxColStart = croppedCols - blockSize + 1;

            for r = 1:step:maxRowStart
                for c = 1:step:maxColStart
                    % Define block bounds
                    r_end = r + blockSize - 1;
                    c_end = c + blockSize - 1;
                    block = grayImg(r:r_end, c:c_end);
            
                    % Normalize and compute threshold
                    blockNorm = uint8(mat2gray(block)*255);
                    thresh = graythresh(blockNorm);
                    bwBlock = imbinarize(blockNorm, thresh);
                    bwBlock = imcomplement(bwBlock);  % Assume lesion is darker
          
                    % Add to accumulation maps
                    iAdaptiveSum(r:r_end, c:c_end) = iAdaptiveSum(r:r_end, c:c_end) + double(bwBlock);
                    weightMap(r:r_end, c:c_end) = weightMap(r:r_end, c:c_end) + 1;
                end
            end

            % Avoid divide-by-zero
            weightMap(weightMap == 0) = 1;
            iAdaptiveAvg = iAdaptiveSum ./ weightMap;
            iAdaptive = iAdaptiveAvg > 0.5;
            % Morphological Post-Processing
            bw = logical(iAdaptive);
            bw = bwareafilt(bw, 1);
            t = 3;
            thin_bg = (~bw) & (bwdist(bw) <= t);
            bw_sealed = bw | thin_bg;
            bw_filled = imfill(bw_sealed, 'holes');
            bw_smooth = imclose(bw_filled, strel('disk', 1));
            bw_smooth = bwareaopen(bw_smooth, 20);
            lesionMask = bw_smooth;
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize, originalCropSize); 
        end

        function mask = segmentSnake(app, grayImg, cropBox, fullSize, initMask, originalCropSize)
            [rows, cols] = size(grayImg);
            numPixels = rows * cols;
            % Adjust iterations based on image size
            baseIterations = 190;
            scalingFactor = 0.005;
            iterations = round(baseIterations + numPixels * scalingFactor);
            iterations = min(iterations, 1500);  % Clamp
            % Run active contour using Chan-Vese model
            lesionMask = activecontour(grayImg, initMask, iterations);
            % Morphological post-processing
            lesionMask = bwareafilt(lesionMask, 1); % keep largest blob only
            lesionMask = imclose(lesionMask, strel('disk', 2));
            lesionMask = imopen(lesionMask, strel('disk', 1));
            lesionMask = bwareaopen(lesionMask, 10);
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize, originalCropSize);
        end

        
        function mask = segmentRegionGrowing(app, colorImg, cropBox, fullSize, roiLocal, originalCropSize)
            % Convert to Lab
            labIm = rgb2lab(im2double(colorImg));
            % Region growing in L*a*b* + spatial space
            L = labIm(:,:,1);
            L_std = std(L(:));
            L_tol = round(9.5 * L_std);
            L_tol = min(max(L_tol, 30), 210);
            lesionMask = regionGrowLab(labIm, roiLocal, L_tol, 0);
            % Morphological post-processing
            lesionMask = bwareafilt(lesionMask, 1); % keep largest blob only
            lesionMask = imfill(lesionMask, 'holes');
            lesionMask = imclose(lesionMask, strel('disk', 3));
            lesionMask = imopen(lesionMask, strel('disk', 1));
            lesionMask = bwareaopen(lesionMask, 15);
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize, originalCropSize);
        end
        
        function mask = segmentKmeans(app, colorImg, cropBox, fullSize, originalCropSize)
            % Convert image to Lab
            labImg = rgb2lab(im2double(colorImg));
            % Run external function
            k = 2;
            maxIters = 30;
            convergenceThresh = 1;
            pixel_labels = segmentKmeansAB(labImg, k, maxIters, convergenceThresh);
            % Pick darkest cluster based on median L*
            L = labImg(:,:,1);
            medians = zeros(1, k);
            for i = 1:k
                medians(i) = median(L(pixel_labels == i));
            end
            [~, darkestIdx] = min(medians);
            lesionMask = (pixel_labels == darkestIdx);
            % Clean mask
            % keep only main blob first
            lesionMask = bwareafilt(lesionMask, 1);
            
            % close small background canals that connect to the exterior
            r = max(1, round(min(size(lesionMask))/300));   % adaptive radius
            lesionMask = imclose(lesionMask, strel('disk', r));
            
            % NOW holes are truly enclosed -> fill them
            lesionMask = imfill(lesionMask, 'holes');
            
            % light cleanup (optional)
            lesionMask = imopen(lesionMask, strel('disk', max(1, r-1)));
            lesionMask = bwareaopen(lesionMask, 20);

            % Reconstruct full-size mask
            mask = app.reconstructFullMask(lesionMask, cropBox, fullSize, originalCropSize);
        end
            

        % ---------------- FEATURE EXTRACTION METHODS ----------------------
        % (1) Assymetry
        function AI = computeAsymmetryIndex(~, mask)
        % Compute average asymmetry index (AI) from horizontal & vertical flips
        % Output: AI in [0,100]
            if ~any(mask(:))
                AI = 0; return;
            end
            % Keep only the largest component
            mask = bwareafilt(logical(mask), 1);
            % Crop to bounding box
            stats = regionprops(mask, 'BoundingBox');
            if isempty(stats)
                AI = 0; return;
            end
            bbox = round(stats(1).BoundingBox);
            subMask = mask(bbox(2):bbox(2)+bbox(4)-1, bbox(1):bbox(1)+bbox(3)-1);
            % Recompute props on cropped mask
            statsSub = regionprops(subMask, 'Centroid','Orientation','Area');
            if isempty(statsSub) || statsSub(1).Area == 0
                AI = 0; return;
            end
            cen  = statsSub(1).Centroid;
            theta = -statsSub(1).Orientation;  % align major axis horizontally
       
            % Center the lesion at the image center
            [rows, cols] = size(subMask);
            dx = cols/2 - cen(1);
            dy = rows/2 - cen(2);
            centered = imtranslate(subMask, [dx, dy], 'FillValues', 0, 'OutputView', 'same');
        
            % PAD to prevent rotation cropping, then rotate
            padSz   = round(size(centered)/2);
            padded  = padarray(centered, padSz, 0, 'both');
            aligned = imrotate(padded, theta, 'nearest', 'loose') > 0;
        
            % Cleanup
            aligned = imfill(aligned, 'holes');
            aligned = bwareaopen(aligned, 5);
        
            % Normalize by POST-rotation area
            areaAligned = nnz(aligned);
            if areaAligned == 0
                AI = 0; return;
            end
        
            % Flip comparisons
            xorH = xor(fliplr(aligned), aligned);
            xorV = xor(flipud(aligned), aligned);
            AI_H = nnz(xorH) / areaAligned;
            AI_V = nnz(xorV) / areaAligned;
            % Percentage score
            AI = 100 * (AI_H + AI_V) / 2;
        end

        % Compute normalized darkness difference between background skin and lesion
        function darknessScore = computeDarknessRelativeToBackground(app, rgbMasked, fullRgbImage)
            Ilab_full = rgb2lab(fullRgbImage);
            Lchannel_full = Ilab_full(:,:,1);
        
            lesionMask = any(rgbMasked > 0, 3);
            backgroundMask = ~lesionMask;
        
            lesionLValues = Lchannel_full(lesionMask);
            backgroundLValues = Lchannel_full(backgroundMask);
        
            meanLesionL = mean(lesionLValues);
            meanBackgroundL = mean(backgroundLValues);
        
            darknessScore = max(0, (meanBackgroundL - meanLesionL) / 100);
        end

        % Count suspicious colors in lesion per paper's suspicious CIELab ranges
        function nColors = countSuspiciousColors(app, rgbMask)
            % Extract lesion pixels
            lesionPixels = reshape(rgbMask, [], 3);
            lesionPixels = lesionPixels(any(lesionPixels > 0, 2), :);
            % Early out if nothing there
            if isempty(lesionPixels)
                nColors = 0;
                return
            end
            lesionLab = rgb2lab(double(lesionPixels)/255);
        
            % K-means clustering to get 7 dominant colors (6 suspicious + black)
            K = 7;
            [idx, centroids] = kmeans(lesionLab, K, 'MaxIter', 200, 'Start', 'plus', 'Replicates', 2, 'EmptyAction', 'singleton');
        
            % Define suspicious color ranges (Lab_min and Lab_max)
            suspiciousColors = struct();
            suspiciousColors.Black      = [0.06  0.27  0.10; 39.91 30.23 22.10];
            suspiciousColors.DarkBrown  = [14.32  6.85  6.96; 47.57 27.14 46.81];
            suspiciousColors.LightBrown = [47.94 11.89 19.86; 71.65 44.81 64.78];
        
            % Exact color prototypes
            suspiciousColors.White    = [100    0      0;    100    0      0];
            suspiciousColors.Red      = [54.29 80.81 69.89; 54.29 80.81 69.89];
            suspiciousColors.BlueGray = [50.28 -30.14 -11.96; 50.28 -30.14 -11.96];
        
            p = 3; % Minkowski parameter
            minkowskiDist = @(x,y) (sum(abs(x - y).^p, 2)).^(1/p);
        
            % Base threshold computed from white to black distance
            whiteLab = suspiciousColors.White(1,:);
            blackLab = suspiciousColors.Black(1,:);
            T_base = minkowskiDist(whiteLab, blackLab) / 2;
        
            % Make White & BlueGray more lenient (empirical decision)
            alphaWhite    = 1.0;  % increase if missing white (e.g., 2.0)
            alphaBlueGray = 2.2;
        
            nColors = 0;
            totalPixels = numel(idx);
            fields = fieldnames(suspiciousColors);
            for i = 1:length(fields)
                colorName = fields{i};
                colorMin = suspiciousColors.(colorName)(1,:);
                colorMax = suspiciousColors.(colorName)(2,:);

                % Choose threshold
                if strcmp(colorName, 'White')
                    T = T_base * alphaWhite;
                elseif strcmp(colorName, 'BlueGray')
                    T = T_base * alphaBlueGray;
                else
                    T = T_base;
                end
        
                % Check distances of centroids to suspicious color ranges
                for cIdx = 1:size(centroids,1)
                    if strcmp(colorName,'Black')
                        % require low L* so we don’t overlap with dark brown/gray
                        if centroids(cIdx,1) > 40
                            continue;
                        end
                    end
                    dist = minkowskiDist(centroids(cIdx,:), colorMin);
                    % For exact colors (White, Red, BlueGray) use distance < T
                    if any(strcmp(colorName, {'White', 'Red', 'BlueGray'}))
                        if dist < T
                            % Check pixel count assigned to this cluster
                            clusterPixels = sum(idx == cIdx);
                            if clusterPixels / totalPixels >= 0.05
                                disp(colorName);
                                nColors = nColors + 1;
                                break
                            end
                        end
                    else
                        % For color ranges, check if centroid is within min/max bounds (component-wise)
                        if all(centroids(cIdx,:) >= colorMin) && all(centroids(cIdx,:) <= colorMax)
                            clusterPixels = sum(idx == cIdx);
                            if clusterPixels / totalPixels >= 0.05
                                disp(colorName);
                                nColors = nColors + 1;
                                break
                            end
                        end
                    end
                end
            end
        end
 
        
        % ---------------- HELPER METHODS (UTILS) -----------------------------
        function fullMask = reconstructFullMask(~, croppedMask, cropBox, fullSize, originalCropSize)
            % If we segmented on a downscaled crop, bring the mask back to the
            % original crop size (nearest preserves binary mask)
            if nargin < 5
                originalCropSize = [size(croppedMask,1), size(croppedMask,2)];
            end
            if ~isequal([size(croppedMask,1), size(croppedMask,2)], originalCropSize)
                croppedMask = imresize(croppedMask, originalCropSize, 'nearest');
            end
        
            fullMask = false(fullSize(1), fullSize(2));
            fullMask(cropBox(2):cropBox(2)+cropBox(4)-1, ...
                     cropBox(1):cropBox(1)+cropBox(3)-1) = logical(croppedMask);
        end
        
        function [imgStd, roiStd, scale, origSize] = limitToMaxSize(~, img, roi, maxH, maxW)
            % Downscale (preserve aspect) so that size(imgStd) <= [maxH, maxW]
            % If already smaller, returns unchanged.
            origSize = [size(img,1) size(img,2)];
            H = origSize(1); W = origSize(2);
            if H <= maxH && W <= maxW
                imgStd = img;
                roiStd = roi;
                scale  = 1;
                return;
            end
            scale = min(maxH / H, maxW / W);
            newH  = max(1, floor(H * scale));
            newW  = max(1, floor(W * scale));
            imgStd = imresize(img, [newH newW]);
            roiStd = imresize(roi, [newH newW], 'nearest');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            folderPath = fullfile(pwd, 'Lesion_Images');
            fullFilePath = fullfile(folderPath, 'Melanoma6.jpg');
            updateimage(app, fullFilePath);
            app.loadedImage = imread(fullFilePath);
            app.lastLoadedFullPath = fullFilePath;
            [~, baseName, ext] = fileparts(fullFilePath);
            app.lastLoadedFile = [baseName, ext];
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: LoadButton_LoadIm
        function LoadButton_LoadImPushed(app, event)
            clc; close all;
            initialFolder = fullfile(pwd, 'Lesion_Images');
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'}, 'Select the Image', initialFolder);
            if isequal(file, 0)
                return;
            end
            fullFilePath = fullfile(path, file);
            app.loadedImage = imread(fullFilePath);
            app.lastLoadedFullPath = fullFilePath;
            app.lastLoadedFile = file;
            imshow(app.loadedImage, 'Parent', app.ImageAxes);

        end

        % Button pushed function: LoadButton_StartDiag
        function LoadButton_StartDiagPushed(app, event)

            if isempty(app.loadedImage)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            im = app.loadedImage;
            % ROI Selection
            figure, imshow(im); title('Draw ROI Around Lesion, then double click to finish.');
            roi = drawfreehand(); wait(roi);
            roiMask = createMask(roi);
            props = regionprops(roiMask, 'BoundingBox');
            cropBox = round(props(1).BoundingBox);
        
            % Crop to maximum 700 x 500 dimensions (keeping aspect)
            maxW = 700; maxH = 500;
            imgCrop = im(cropBox(2):cropBox(2)+cropBox(4)-1, ...
                         cropBox(1):cropBox(1)+cropBox(3)-1, :);
            roiLocal = roiMask(cropBox(2):(cropBox(2)+cropBox(4)-1), ...
                   cropBox(1):(cropBox(1)+cropBox(3)-1));
            originalCropSize = [size(imgCrop,1), size(imgCrop,2)];
            [imgCropStd, roiLocalStd] = app.limitToMaxSize(imgCrop, roiLocal, maxH, maxW);

            % Preprocess on standardized images
            imgGray = app.preprocessGrayscale(imgCropStd);
            imgColor = app.preprocessColor(imgCropStd);
        
            % Segmentations
            maskOtsu     = app.segmentOtsu(imgGray, cropBox, size(im), originalCropSize);
            maskedRgbOtsu = bsxfun(@times, im, cast(maskOtsu, 'like', im));
            imshow(maskedRgbOtsu, 'Parent', app.ImageAxes_Otsu);

            maskAdaptive = app.segmentAdaptiveThreshold(imgGray, cropBox, size(im), originalCropSize);
            maskedRgbAdaptive = bsxfun(@times, im, cast(maskAdaptive, 'like', im));
            imshow(maskedRgbAdaptive, 'Parent', app.ImageAxes_AT);

            maskSnake    = app.segmentSnake(imgGray, cropBox, size(im), roiLocalStd, originalCropSize);
            maskedRgbSnake = bsxfun(@times, im, cast(maskSnake, 'like', im));
            imshow(maskedRgbSnake, 'Parent', app.ImageAxes_Snake);

            maskRegion   = app.segmentRegionGrowing(imgColor, cropBox, size(im), roiLocalStd, originalCropSize);
            maskedRgbRegion = bsxfun(@times, im, cast(maskRegion, 'like', im));
            imshow(maskedRgbRegion, 'Parent', app.ImageAxes_RegionGrowing);
            
            maskKmeans   = app.segmentKmeans(imgColor, cropBox, size(im), originalCropSize);
            maskedRgbKMeans = bsxfun(@times, im, cast(maskKmeans , 'like', im));
            imshow(maskedRgbKMeans, 'Parent', app.ImageAxes_kMeans);
            
            app.otsuMask = maskOtsu;
            app.ATMask = maskAdaptive;
            app.snakeMask = maskSnake;
            app.regionMask = maskRegion;
            app.KMask = maskKmeans;

            choice = app.SelectMaskDropDown.Value;
            switch choice
                case "Otsu's Method"
                    app.selectedMask = maskOtsu;
                    app.selectedRgbMask = maskedRgbOtsu;
                case "Adaptive Thresholding"
                    app.selectedMask = maskAdaptive;
                    app.selectedRgbMask = maskedRgbAdaptive;
                case "Snake (Active Contour)"
                    app.selectedMask = maskSnake;
                    app.selectedRgbMask = maskedRgbSnake;
                case "Region Growing"
                    app.selectedMask = maskRegion;
                    app.selectedRgbMask = maskedRgbRegion;
                case "K Means Clustering"
                    app.selectedMask = maskKmeans;
                    app.selectedRgbMask = maskedRgbKMeans;
                otherwise
                    uialert(app.UIFigure, 'Invalid selection.', 'Error');
                    return;
            end

        end

        % Button pushed function: LoadButton_ConfirmChoice
        function LoadButton_ConfirmChoicePushed(app, event)
            choice = app.SelectMaskDropDown.Value;
            switch choice
                case "Otsu's Method"
                    app.selectedMask = app.otsuMask;
                case "Adaptive Thresholding"
                    app.selectedMask = app.ATMask;
                case "Snake (Active Contour)"
                    app.selectedMask = app.snakeMask;
                case "Region Growing"
                    app.selectedMask = app.regionMask;
                case "K Means Clustering"
                    app.selectedMask = app.KMask;
                otherwise
                    uialert(app.UIFigure, 'Invalid selection.', 'Error');
                    return;
            end
            im = app.loadedImage;
            app.selectedRgbMask = bsxfun(@times, im, cast(app.selectedMask, 'like', im));

            % Feature Extraction
            stats=regionprops(app.selectedMask,'all');
            allAreas=[stats.Area];
            [maxArea,~]=max(allAreas);
            allPerimeters = [stats.Perimeter];
            maxPerimeter = max(allPerimeters);

            % A: Assymetry
            AI = app.computeAsymmetryIndex(app.selectedMask);
            hasShapeAsym = AI > 8;
            hasInternalAsym = AI > 20;

            % B: Abrupt border cutoff
            edgePixels = edge(app.selectedMask, 'sobel');
            borderAbrupt = sum(edgePixels(:)) / nnz(app.selectedMask);
            hasBorderCutoff = borderAbrupt <= 0.03;  % Empirical threshold
            % B: Circularity
            circularity = maxPerimeter  .^ 2 ./ (4 * pi* maxArea);
            hasPoorCircularity = circularity >= 2.5;

            % C: Darkness
            darknessScore = computeDarknessRelativeToBackground(app,app.selectedRgbMask, app.loadedImage);
            hasHighDarkness = darknessScore > 0.33;
            % C: Number of distinct colors
            nSuspiciousColors = app.countSuspiciousColors(app.selectedRgbMask);
            hasMultipleColors = nSuspiciousColors >= 3;

            % User input for Evolution (E)
            evolutionSelection = app.EvolutionDropDown.Value;
            switch evolutionSelection
                case "Yes"
                    hasEvolution = 1;
                case "No"
                    hasEvolution = -1;
                case "Uncertain"
                    hasEvolution = 0;
            end

            % Final classification score using ABC-point-like system
            score = hasShapeAsym + hasInternalAsym ...
            + hasPoorCircularity + 0.5*hasBorderCutoff + 1.5*hasMultipleColors + 0.5*hasHighDarkness + hasEvolution;

            % Diagnosis logic
            if score >= 4.0
                diagnosis = 'Likely Malignant (Melanoma)';
            elseif score >= 2.5
                diagnosis = 'Suspicious: Needs Dermatologist Review.';
            else
                diagnosis = 'Likely Benign';
            end

            resultsStr = sprintf([
            'Feature Scores:\n', ...
            'Asymmetry Index: %.2f (Threshold 8) → %s\n', ...
            'Internal Asymmetry: %.2f (Threshold 20) → %s\n', ...
            'Circularity: %.5f (Threshold 2.5) → %s\n', ...
            'Abrupt Border (Local): %.5f (Threshold 0.03) → %s\n', ...
            'Number of Suspicious Colors: %d (Threshold 3) → %s\n', ...
            'Darkness Score: %.2f (Threshold 0.33) → %s\n\n', ...
            'Final Score: %d\n', ...
            'Diagnosis: %s'], ...
            AI, bool2str(hasShapeAsym), ...
            AI, bool2str(hasInternalAsym), ...
            circularity, bool2str(hasPoorCircularity), ...
            borderAbrupt, bool2str(hasBorderCutoff), ...
            nSuspiciousColors, bool2str(hasMultipleColors), ...
            darknessScore, bool2str(hasHighDarkness), ...
            score, diagnosis);

            % Append warning if evolution is reported
            if hasEvolution
                resultsStr = [resultsStr, '\n Evolution reported: Lesion has changed, which is the strongest indicator of malignancy. Dermatological evaluation is strongly recommended.'];
            end
            
            app.TextArea.Value = splitlines(resultsStr);

            % ==== Append results to CSV log ====
            logFile = 'melanoma_results.csv'; % file in current folder
            if ~isempty(app.lastLoadedFile)
                imageLabel = app.lastLoadedFile;
            elseif ~isempty(app.lastLoadedFullPath)
                [~, bn, ex] = fileparts(app.lastLoadedFullPath);
                imageLabel = [bn, ex];
            else
                imageLabel = 'UnknownImage';
            end
            maskName  = choice;
            timestamp = datetime('now','Format','yyyy-MM-dd HH:mm:ss');
            
            row = table( ...
                string(timestamp), ...
                string(imageLabel), ...
                string(maskName), ...
                AI, ...
                hasShapeAsym, ...
                hasInternalAsym, ...
                circularity, ...
                hasPoorCircularity, ...
                borderAbrupt, ...
                hasBorderCutoff, ...
                nSuspiciousColors, ...
                hasMultipleColors, ...
                darknessScore, ...
                hasHighDarkness, ...
                score, ...
                string(diagnosis), ...
                'VariableNames', { ...
                    'DateTime','ImageLabel','MaskMethod', ...
                    'AsymmetryIndex','HasShapeAsym','HasInternalAsym', ...
                    'Circularity','HasPoorCircularity', ...
                    'BorderAbrupt','HasBorderCutoff', ...
                    'NumSuspiciousColors','HasMultipleColors', ...
                    'DarknessScore','HasHighDarkness', ...
                    'FinalScore','Diagnosis'});
            
            if isfile(logFile)
                writetable(row, logFile, 'WriteMode', 'Append');
            else
                writetable(row, logFile);
            end
            % ==== End CSV log ====

            function str = bool2str(b)
                if b
                    str = 'Yes';
                else
                    str = 'No';
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes_Otsu = uiaxes(app.UIFigure);
            title(app.ImageAxes_Otsu, 'Otsu''s Method')
            app.ImageAxes_Otsu.XTick = [];
            app.ImageAxes_Otsu.XTickLabel = {'[ ]'};
            app.ImageAxes_Otsu.YTick = [];
            app.ImageAxes_AT = uiaxes(app.UIFigure);
            title(app.ImageAxes_AT, 'Adaptive Thresholding')
            app.ImageAxes_AT.XTick = [];
            app.ImageAxes_AT.XTickLabel = {'[ ]'};
            app.ImageAxes_AT.YTick = [];
            app.ImageAxes_Snake = uiaxes(app.UIFigure);
            title(app.ImageAxes_Snake, 'Snake (Active Contour)')
            app.ImageAxes_Snake.XTick = [];
            app.ImageAxes_Snake.XTickLabel = {'[ ]'};
            app.ImageAxes_Snake.YTick = [];
            app.ImageAxes_RegionGrowing = uiaxes(app.UIFigure);
            title(app.ImageAxes_RegionGrowing, 'Region Growing')
            app.ImageAxes_RegionGrowing.XTick = [];
            app.ImageAxes_RegionGrowing.XTickLabel = {'[ ]'};
            app.ImageAxes_RegionGrowing.YTick = [];
            app.ImageAxes_kMeans = uiaxes(app.UIFigure);
            title(app.ImageAxes_kMeans, 'K Means Clustering')
            app.ImageAxes_kMeans.XTick = [];
            app.ImageAxes_kMeans.XTickLabel = {'[ ]'};
            app.ImageAxes_kMeans.YTick = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.LoadButton_LoadIm = uibutton(app.UIFigure, 'push');
            app.LoadButton_LoadIm.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_LoadImPushed, true);
            app.LoadButton_LoadIm.Text = 'Load Lesion Image';
            app.LoadButton_StartDiag = uibutton(app.UIFigure, 'push');
            app.LoadButton_StartDiag.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_StartDiagPushed, true);
            app.LoadButton_StartDiag.Text = 'Start Diagnosis!';
            app.LoadButton_ConfirmChoice = uibutton(app.UIFigure, 'push');
            app.LoadButton_ConfirmChoice.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_ConfirmChoicePushed, true);
            app.LoadButton_ConfirmChoice.Text = 'Confirm Selection';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.FinalResultsLabel = uilabel(app.UIFigure);
            app.FinalResultsLabel.Text = {'Final results will be displayed here.'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.SelectMaskDropDownLabel = uilabel(app.UIFigure);
            app.SelectMaskDropDownLabel.Text = 'Select Mask:';
            app.SelectMaskDropDown = uidropdown(app.UIFigure);
            app.SelectMaskDropDown.Items = {'Otsu''s Method', 'Adaptive Thresholding', 'Snake (Active Contour)', 'Region Growing', 'K Means Clustering'};
            app.SelectMaskDropDown.Value = 'Otsu''s Method';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1:';
            app.ClicktobeginsegmentationonceimageisloadedLabel = uilabel(app.UIFigure);
            app.ClicktobeginsegmentationonceimageisloadedLabel.Text = '2: Click to begin segmentation once image is loaded.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Please select the segmentation mask from the four generated below that:'; ''; '- Captures the full lesion, including faint or blurry regions.'; '- Includes lesion boundaries (often lighter in color); these are critical for diagnosing melanoma.'; '- Avoids including too much surrounding skin.'; '- Preserves the lesion’s shape; asymmetry is an important indicator for diagnosis.'};
            app.ChoiceofSegmentationMaskLabel = uilabel(app.UIFigure);
            app.ChoiceofSegmentationMaskLabel.Text = '4: Choice of Segmentation Mask.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.EvolutionLabel = uilabel(app.UIFigure);
            app.EvolutionLabel.Text = 'Evolution:';
            app.EvolutionDropDown = uidropdown(app.UIFigure);
            app.EvolutionDropDown.Items = {'Uncertain', 'Yes', 'No'};
            app.EvolutionDropDown.Value = 'Uncertain';
            app.HasthelesionchangedwithinthepastweeksmonthsLabel = uilabel(app.UIFigure);
            app.HasthelesionchangedwithinthepastweeksmonthsLabel.Text = '3: Has the lesion changed within the past weeks/months?';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'Please indicate in the dropdown if you have noticed any changes in the lesion''s size, shape,'; 'color, surface texture (such as bleeding or scaling), or symptoms (such as itching or tenderness).'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MelanomaDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end